classdef hw1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        OpenButton               matlab.ui.control.Button
        ImageThresholdingButton  matlab.ui.control.Button
        ThresholdEditField       matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.img = imread('Test1.jpg');         % 讀取影像並存到 img
            imshow(app.img, 'parent', app.UIAxes); % 顯示影像在物件 UIAxes 上
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
      
        end

        % Button pushed function: ImageThresholdingButton
        function ImageThresholdingButtonPushed(app, event)
            threshold = app.ThresholdEditField.Value
            img_new = app.img;                        % 建立新變數存放二值化後的影像
            for i = 1:size(app.img, 1)            % size()：回傳矩陣大小，size(img, 1) 回傳列數，
                for j = 1:size(app.img, 2)        % size(img, 2) 回傳行數
                    if(app.img(i,j) > threshold)  % 如果灰度值大於閥值，則此像素為白色
                        img_new(i,j) = 255;
                    else                          % 反之，則此像素為黑色
                        img_new(i,j) = 0;
                    end
                end
            end
            imshow(img_new, 'parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ImageThresholdingButton = uibutton(app.UIFigure, 'push');
            app.ImageThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @ImageThresholdingButtonPushed, true);
            app.ImageThresholdingButton.Text = 'Image Thresholding';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hw1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end