classdef hw3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        RegressionButton  matlab.ui.control.Button
        OpenButton        matlab.ui.control.Button
        DAY1              matlab.ui.control.UIAxes
        Scatter_Plot      matlab.ui.control.UIAxes
        DAY2              matlab.ui.control.UIAxes
        DAY2_Corrected    matlab.ui.control.UIAxes
        Combined          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         % Description
        img1
        img2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.img1=imread('Satellite Image Day1 (B1).tif')
            app.img2=imread('Satellite Image Day2 (B1).tif')
            imshow(app.img1,'parent',app.DAY1)
            imshow(app.img2,'Parent',app.DAY2)
            
            scatter(app.Scatter_Plot, app.img2(:), app.img1(:), 10, 'filled');
            % axis(ax, [xmin xmax ymin ymax])：設定圖軸的範圍
            axis(app.Scatter_Plot, [0 255 0 255]);
            % xticks(), yticks()：設定 x, y 軸刻度，0 到 250 每 50 為一個間隔
            xticks(app.Scatter_Plot, 0:50:250);
            yticks(app.Scatter_Plot, 0:50:250);
            % xlabel(), ylabel()：設定 x, y 軸標題
            xlabel(app.Scatter_Plot, 'Satellite Image Day2 (B1)');
            ylabel(app.Scatter_Plot, 'Satellite Image Day1 (B1)');

        end

        % Button pushed function: RegressionButton
        function RegressionButtonPushed(app, event)
            % 建立 y 向量與 C 矩陣
            y = double(app.img1(:));
            % 把outlier的位置挑出來
            thres_map = y >100;
            y(thres_map) = 0;
            v1 = ones(size(y, 1), 1);
            img2_thrs = app.img2;
            img2_thrs(thres_map) =0;
            v2 = double(img2_thrs(:));
            size(double(app.img2))
            % 迴歸
            %x1=(app.img1(:))
            %size(double(app.img2))
            C = [ones(469*473,1),double(app.img2(:))]
            a = ((C.')*C)^-1*(C.')*y;
            % y = Ca，這裡要放DAY2原始影像而非thresholding影像
            img2_new = C*a;
            % 轉換 Day2 影像
            img2_new = uint8(img2_new);
            img2_new = reshape(img2_new, size(app.img2));
            % 合併Day1和Day2
            img_comb = app.img1;
            img_comb(floor(size(img2_new, 1)/2):end, :)= img2_new(floor(size(img2_new, 1)/2):end, :);
            imshow(img2_new, 'parent', app.DAY2_Corrected);
            imshow(img_comb, 'parent', app.Combined);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Combined = uiaxes(app.UIFigure);
            title(app.Combined, 'Combined')
            app.DAY2_Corrected = uiaxes(app.UIFigure);
            title(app.DAY2_Corrected, 'DAY2 Corrected')
            app.DAY2 = uiaxes(app.UIFigure);
            title(app.DAY2, 'DAY2')
            app.Scatter_Plot = uiaxes(app.UIFigure);
            title(app.Scatter_Plot, 'Scatter Plot')
            app.DAY1 = uiaxes(app.UIFigure);
            title(app.DAY1, 'DAY1')
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.RegressionButton = uibutton(app.UIFigure, 'push');
            app.RegressionButton.ButtonPushedFcn = createCallbackFcn(app, @RegressionButtonPushed, true);
            app.RegressionButton.Text = 'Regression';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hw3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end