classdef hw2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        angleEditField       matlab.ui.control.NumericEditField
        angleEditFieldLabel  matlab.ui.control.Label
        yEditField           matlab.ui.control.NumericEditField
        yEditFieldLabel      matlab.ui.control.Label
        xEditField           matlab.ui.control.NumericEditField
        xEditFieldLabel      matlab.ui.control.Label
        TransformButton      matlab.ui.control.Button
        OpenButton           matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.img = imread('Test1.jpg');
            imshow(app.img,'parent',app.UIAxes);
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            
        end

        % Button pushed function: TransformButton
        function TransformButtonPushed(app, event)
            x = app.xEditField.Value;
            y = app.yEditField.Value;
            angle = app.angleEditField.Value;
            R = [cosd(angle),-sind(angle);sind(angle),cosd(angle)]
            T = [x;y]
            img_new = zeros(size(app.img));
            img_new = uint8(img_new);
            for c = 1:size(img_new,2)
                for r = 1:size(img_new,1)
                    p_new=[c;r];
                    p=round(R^(-1)*(p_new-T));
                    if(1<=p(1)&&p(1)<=size(app.img,2)&&1<=p(2)&&p(2)<=size(app.img,1))
                        img_new(r,c)=app.img(p(2),p(1));
                    end
                end
            end     
            imshow(img_new,'parent',app.UIAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.TransformButton = uibutton(app.UIFigure, 'push');
            app.TransformButton.ButtonPushedFcn = createCallbackFcn(app, @TransformButtonPushed, true);
            app.TransformButton.Text = 'Transform';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.angleEditFieldLabel = uilabel(app.UIFigure);
            app.angleEditFieldLabel.Text = 'angle';
            app.angleEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hw2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end