classdef hw5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        PCAButton   matlab.ui.control.StateButton
        LoadButton  matlab.ui.control.Button
        band6       matlab.ui.control.UIAxes
        band5       matlab.ui.control.UIAxes
        band4       matlab.ui.control.UIAxes
        band3       matlab.ui.control.UIAxes
        band2       matlab.ui.control.UIAxes
        band1       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imgs={};
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.PCAButton.Value = false
            app.imgs={}
            file = {'Band1.tiff','Band2.tiff','Band3.tiff','Band4.tiff','Band5.tiff','Band8.tiff'};
            band = [app.band1,app.band2,app.band3,app.band4,app.band5,app.band6]
            for i=1:6
                app.imgs{i}=imread(file{i})
                imshow(app.imgs{i},'Parent',band(i))
            end
        end

        % Value changed function: PCAButton
        function PCAButtonValueChanged(app, event)
            value = app.PCAButton.Value;
            %size(app.imgs{1}(:))
            %size(app.imgs(1)(:))
            band = [app.band1,app.band2,app.band3,app.band4,app.band5,app.band6]
            if value
                X=[app.imgs{1}(:),app.imgs{2}(:),app.imgs{3}(:),app.imgs{4}(:),app.imgs{5}(:),app.imgs{6}(:)]
                X=double(X)
                %size(X)
                m=mean(X)
                Cov=cov(X)
                [U,S,V]=svd(Cov)
                [d,index]=sort(diag(S),'descend')
                V=V(:,index)
                U=U(:,index)
                y=U'*V'*(X-m)'%這行不會寫QQ
                img_size=size(app.imgs{1})
                for i=1:length(band)
                    img=reshape(y(i,:),img_size)
                    imshow(img,'Parent',band(i),'DisplayRange',[])
                end
            else
                for i=1:6
                    imshow(app.imgs{i},'Parent',band(i))
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.band1 = uiaxes(app.UIFigure);
            zlabel(app.band1, 'Z')
            app.band2 = uiaxes(app.UIFigure);
            zlabel(app.band2, 'Z')
            app.band3 = uiaxes(app.UIFigure);
            zlabel(app.band3, 'Z')
            app.band4 = uiaxes(app.UIFigure);
            zlabel(app.band4, 'Z')
            app.band5 = uiaxes(app.UIFigure);
            zlabel(app.band5, 'Z')
            app.band6 = uiaxes(app.UIFigure);
            zlabel(app.band6, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PCAButton = uibutton(app.UIFigure, 'state');
            app.PCAButton.ValueChangedFcn = createCallbackFcn(app, @PCAButtonValueChanged, true);
            app.PCAButton.Text = 'PCA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hw5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end